classdef cableactuation2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DoneButton            matlab.ui.control.Button
        PathtypeListBoxLabel  matlab.ui.control.Label
        PathtypeListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        done % Description
        Tr % Description
        dc_fn
        dc
        div_end
        iL % Description
        i_sact
        div_start % Description
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function closewindow(app)
            while app.done==0
                pause(0.05);
            end
            close(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Tr, ii, iL)
            % Create ChoosebasisforthesoftpieceLabel
            Label1          = uilabel(app.UIFigure);
            Label1.Position = [70 250 200 22];
            Label1.Text     = ['Setup path for actuator ',num2str(ii), ' on link ',num2str(iL)];
            app.done   = 0;
            app.Tr     = Tr;
            app.iL     = iL;
            app.i_sact = ii;
            app.Tr.plotq0(iL);
            app.closewindow;
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            dci = cell(1,app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1);
            
            if app.div_start>0
                d=1;
                for j=1:app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1
                    
                    if j==app.div_start
                        x=0;
                    end
                    if j>=app.div_start&&j<=app.div_end
                        nip = app.Tr.CVTwists{app.Tr.LinkIndex(app.iL)}(j+1).nip;
                        Xs     = app.Tr.CVTwists{app.Tr.LinkIndex(app.iL)}(j+1).Xs;
                        ld     = app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).lp{j};
                        
                        dci{d}      = zeros(3,nip);%at all significant points
                        for ii=1:nip
                            dci{d}(:,ii)  = app.dc_fn(x+Xs(ii)*ld);
                        end
                    end
                    d=d+1;
                end
                CableDisplay(app.Tr,app.dc_fn,app.iL,app.div_start,app.div_end)
            end
            
            div_endi=app.div_end;
            div_starti=app.div_start;
            dc_fni=app.dc_fn;
            save('cableactuation.mat','dc_fni','dci','div_starti','div_endi')
            app.done=1;
        end

        % Value changed function: PathtypeListBox
        function PathtypeListBoxValueChanged(app, event)
            
            close(1);
            app.Tr.plotq0(app.iL);
            
            value = app.PathtypeListBox.Value;
            switch value
                
                case 'Constant'
                close(1);
                app.Tr.plotq0(app.iL);
                
                gooddiv=0;
                while ~gooddiv
                    
                    prompt           = {'y co-ordinate of starting point:',...
                                        'z co-ordinate of starting point:',...
                                        'Division number corresponding to the starting point:',...
                                        'Division number corresponding to the ending point:'};
                    dlgtitle         = 'Actuator parameters for constant path';
                    definput         = {'0.007','0','1',num2str(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)};
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'normal';
                    cb_input         = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
                    y1    = str2num(cb_input{1});
                    z1    = str2num(cb_input{2});
                    
                    app.div_start = str2num(cb_input{3});
                    app.div_end   = str2num(cb_input{4});
                
                    if app.div_end<app.div_start||app.div_start<1||app.div_end>(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)
                        gooddiv=0;
                        uiwait(msgbox('INCORRECT DIVISION NUMBER(S)','Error','error'))
                    else
                        gooddiv=1;
                    end
                
                end
  
                app.dc_fn  = str2func(['@(X) [0;' num2str(y1) ';' num2str(z1) ']']);
            
                CableDisplay(app.Tr,app.dc_fn,app.iL,app.div_start,app.div_end)
                figure(app.UIFigure)
                
                case 'Oblique'
                close(1); 
                app.Tr.plotq0(app.iL);
                
                gooddiv=0;
                while ~gooddiv
                    
                    prompt           = {'y co-ordinate of starting point:',...
                                        'z co-ordinate of starting point:',...
                                        'Division number corresponding to the starting point:',...
                                        'y co-ordinate of ending point:',...
                                        'z co-ordinate of ending point:',...
                                        'Division number corresponding to the ending point:'};
                    dlgtitle         = 'Actuator parameters for oblique path';
                    definput         = {'0.007','0','1','0','0.007',num2str(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)};
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'normal';
                    cb_input         = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
                    
                    y1    = str2num(cb_input{1});
                    z1    = str2num(cb_input{2});

                    y2    = str2num(cb_input{4});
                    z2    = str2num(cb_input{5});
                    
                    app.div_start = str2num(cb_input{3});
                    app.div_end   = str2num(cb_input{6});
                
                    if app.div_end<app.div_start||app.div_start<1||app.div_end>(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)
                        gooddiv=0;
                        uiwait(msgbox('INCORRECT DIVISION NUMBER(S)','Error','error'))
                    else
                        gooddiv=1;
                    end
                
                end
                
                    
                Lc = 0;
                for jj=app.div_start:app.div_end
                    Lc=Lc+app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).lp{jj};
                end
                
                app.dc_fn  = str2func(['@(X) [0;' num2str(y1) '+' num2str((y2-y1)/Lc) '*X' ';' num2str(z1) '+' num2str((z2-z1)/Lc) '*X]']);

                CableDisplay(app.Tr,app.dc_fn,app.iL,app.div_start,app.div_end)
                figure(app.UIFigure)
                
                case 'None'
                    close(1);
                    app.Tr.plotq0(app.iL);
                    
                    app.div_start = 0;
                    app.div_end   = 0;
                case 'Select'
                    close(1);
                    app.Tr.plotq0(app.iL);
                    
                    app.div_start = 0;
                    app.div_end   = 0;    
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.PathtypeListBoxLabel = uilabel(app.UIFigure);
            app.PathtypeListBoxLabel.Text = 'Path type';
            app.PathtypeListBox = uilistbox(app.UIFigure);
            app.PathtypeListBox.Items = {'Select', 'None', 'Constant', 'Oblique'};
            app.PathtypeListBox.ValueChangedFcn = createCallbackFcn(app, @PathtypeListBoxValueChanged, true);
            app.PathtypeListBox.Value = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cableactuation2GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end