classdef cableactuationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DoneButton            matlab.ui.control.Button
        PathtypeListBoxLabel  matlab.ui.control.Label
        PathtypeListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        done % Description
        Tr % Description
        dc
        dcp
        div_end
        div_start % Description
        iL % Description
        i_sact
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function closewindow(app)
            while app.done==0
                pause(0.05);
            end
            close(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Tr, ii, iL)
            % Create ChoosebasisforthesoftpieceLabel
            Label1          = uilabel(app.UIFigure);
            Label1.Position = [70 250 200 22];
            Label1.Text     = ['Setup path for actuator ',num2str(ii), ' on link ',num2str(iL)]; 
            app.done   = 0;
            app.Tr     = Tr;
            app.iL     = iL;
            app.i_sact = ii;
     
            app.Tr.plotq0(iL);
            app.closewindow;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            dci  = cell(1,app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1);
            dcpi = cell(1,app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1);
            
            if app.div_start>0
                syms t;
                d=1;
                for j=1:app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1
                    
                    if j==app.div_start
                        x=0;
                    end
                    
                    if j>=app.div_start&&j<=app.div_end
                        lpd     = app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).lp{j};
                        Xs      = app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).Xs{j};
                        nGauss  = app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).nGauss{j};
                        dci{d}  = zeros(3,nGauss);
                        dcpi{d} = zeros(3,nGauss);
                        for ii=1:nGauss
                            dci{d}(2,ii)  = app.dc{2}(x+Xs(ii)*lpd);
                            dci{d}(3,ii)  = app.dc{3}(x+Xs(ii)*lpd);
                            dcpi{d}(2,ii) = app.dcp{2}(x+Xs(ii)*lpd);
                            dcpi{d}(3,ii) = app.dcp{3}(x+Xs(ii)*lpd);
                        end
                        x=x+lpd;
                    end
                    d=d+1;
                end
                CableDisplay(app.dc{2},app.dc{3},app.i_sact,app.iL,app.div_start,app.div_end,app.Tr,1)
                
            end

            close all
            div_endi=app.div_end;
            div_starti=app.div_start;
            save('cableactuation.mat','dci','dcpi','div_starti','div_endi')
            app.done=1;
        end

        % Value changed function: PathtypeListBox
        function PathtypeListBoxValueChanged(app, event)
            

            app.Tr.plotq0(app.iL);
            
            value = app.PathtypeListBox.Value;
            switch value
                
                case 'Constant'
                    
                close all
                app.Tr.plotq0(app.iL);
                
                gooddiv=0;
                while ~gooddiv
                
                    prompt = {'y co-ordinate of starting point:',...
                              'z co-ordinate of starting point:',...
                              'Division number corresponding to the starting point:',...
                              'Division number corresponding to the ending point:'};
                    dlgtitle         = 'Actuator parameters for constant path';
                    definput         = {'0.02','0','1',num2str(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)};
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'normal';
                    cb_input         = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
                    
                    y1    = str2num(cb_input{1});
                    z1    = str2num(cb_input{2});
                    div_start1 = str2num(cb_input{3});
                    div_end1   = str2num(cb_input{4});
                    
                    if div_end1<div_start1||div_start1<1||div_end1>(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)
                        gooddiv=0;
                        uiwait(msgbox('INCORRECT DIVISION NUMBER(S)','Error','error'))
                    else
                        gooddiv=1;
                    end
                
                end
                
                    
                x1 = 0;
                x2 = 0;
                for jj=div_start1:div_end1
                    x2=x2+app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).lp{jj};
                end
                
                syms t
                y = sym(y1);
                z = sym(z1);
                
                y_fn = str2func(['@(t)' num2str(y1)]);
                z_fn = str2func(['@(t)' num2str(z1)]);
                
                CableDisplay(y_fn,z_fn,app.i_sact,app.iL,div_start1,div_end1,app.Tr,0)
                app.PathtypeListBox.Value = 'Select';
                figure(app.UIFigure)
                
                case 'Oblique'
                    
                close all
                app.Tr.plotq0(app.iL);
                
                gooddiv=0;
                while ~gooddiv
                    prompt = {'y co-ordinate of starting point:',...
                              'z co-ordinate of starting point:',...
                              'Division number corresponding to the starting point:',...
                              'y co-ordinate of ending point:',...
                              'z co-ordinate of ending point:',...
                              'Division number corresponding to the ending point:'};
                    dlgtitle         = 'Actuator parameters for oblique path';
                    definput         = {'0.02','0','1','0','0.02',num2str(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)};
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'normal';
                    cb_input         = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
                    
                    y1    = str2num(cb_input{1});
                    z1    = str2num(cb_input{2});
                    div_start1 = str2num(cb_input{3});
                    y2    = str2num(cb_input{4});
                    z2    = str2num(cb_input{5});
                    div_end1 = str2num(cb_input{6});
                    
                    if div_end1<div_start1||div_start1<1||div_end1>(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)
                        gooddiv=0;
                        uiwait(msgbox('INCORRECT DIVISION NUMBER(S)','Error','error'))
                    else
                        gooddiv=1;
                    end
                    
                end
                
                    
                x1 = 0;
                x2 = 0;
                for jj=div_start1:div_end1
                    x2=x2+app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).lp{jj};
                end
                
                syms t
                x = t;
                y = y1+(y2-y1)/(x2-x1)*x;
                z = z1+(z2-z1)/(x2-x1)*x;
                
                if has(y,t)
                    y_fn = matlabFunction(y);
                else
                    y_fn = str2func(['@(t)' num2str(y1)]);
                end
                if has(z,t)
                    z_fn = matlabFunction(z);
                else
                    z_fn = str2func(['@(t)' num2str(z1)]);
                end
                
                
                CableDisplay(y_fn,z_fn,app.i_sact,app.iL,div_start1,div_end1,app.Tr,0)
                app.PathtypeListBox.Value = 'Select';
                figure(app.UIFigure)
                
                case 'Helical'
                
                close all
                app.Tr.plotq0(app.iL); 
                
                gooddiv=0;
                while ~gooddiv
                    
                    prompt = {'y co-ordinate of starting point:',...
                              'z co-ordinate of starting point:',...
                              'Pitch (m/rad)'...
                              'Clockwise (CW) or Counterclockwise (CCW)'...
                              'Division number corresponding to the starting point:',...
                              'Division number corresponding to the ending point:'};
                    dlgtitle         = 'Actuator parameters for helical path';
                    definput         = {'0.02','0','0.05','CCW','1',num2str(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)};
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'normal';
                    cb_input         = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
                    
                    y1 = str2num(cb_input{1});
                    z1 = str2num(cb_input{2});
                    p  = str2num(cb_input{3});
                    dir=1;
                    if strcmp('CW',cb_input{4})
                        dir=-1;
                    end
                    div_start1 = str2num(cb_input{5});
                    div_end1   = str2num(cb_input{6});
                    
                    if div_end1<div_start1||div_start1<1||div_end1>(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)
                        gooddiv=0;
                        uiwait(msgbox('INCORRECT DIVISION NUMBER(S)','Error','error'))
                    else
                        gooddiv=1;
                    end
                    
                end
                    
                x1 = 0;
                x2 = 0;
                for jj=div_start1:div_end1
                    x2=x2+app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).lp{jj};
                end
                
                syms t
                x  = t;
                r  = sqrt(y1^2+z1^2);
                t0 = atan2(z1,y1);
                y  = r*sin(t0+dir*t/p);
                z  = r*cos(t0+dir*t/p);
                
                y_fn = matlabFunction(y);
                z_fn = matlabFunction(z);
                

                CableDisplay(y_fn,z_fn,app.i_sact,app.iL,div_start1,div_end1,app.Tr,0)
                app.PathtypeListBox.Value = 'Select';
                figure(app.UIFigure)
                
                case 'Custom'
                    
                close all
                app.Tr.plotq0(app.iL); 
                
                gooddiv=0;
                while ~gooddiv
                    
                    prompt = {'Enter y as a differentiable function of X (X=0 at starting point):',...
                              'Enter z as a differentiable function of X (X=0 at starting point):',...
                              'Division number corresponding to the starting point:',...
                              'Division number corresponding to the ending point:'};
                    dlgtitle         = 'Actuator parameters for custom path';
                    definput         = {'0.01*sin(X)^2','0.01*sin(X)*cos(X)','1',num2str(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)};
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'normal';
                    cb_input         = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
                    
                    ystr  = cb_input{1};
                    zstr  = cb_input{2};
                    div_start1 = str2num(cb_input{3});
                    div_end1   = str2num(cb_input{4});
                
                    if div_end1<div_start1||div_start1<1||div_end1>(app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).npie-1)
                        gooddiv=0;
                        uiwait(msgbox('INCORRECT DIVISION NUMBER(S)','Error','error'))
                    else
                        gooddiv=1;
                    end
                    
                end
                
                    
                x1 = 0;
                x2 = 0;
                for jj=div_start1:div_end1
                    x2=x2+app.Tr.VLinks(app.Tr.LinkIndex(app.iL)).lp{jj};
                end
                
                syms t X
                y = str2sym(ystr);
                z = str2sym(zstr);
                y = subs(y,X,t);
                z = subs(z,X,t);
                
                y_fn = matlabFunction(y);
                z_fn = matlabFunction(z);
            
                CableDisplay(y_fn,z_fn,app.i_sact,app.iL,div_start1,div_end1,app.Tr,0)
                app.PathtypeListBox.Value = 'Select';
                figure(app.UIFigure)
                
                case 'None'
                    
                close all
                app.Tr.plotq0(app.iL);
                
                div_start1 = 0;
                div_end1   = 0;
                figure(app.UIFigure)
            end
            if div_start1>0
                app.dc   = {0;y_fn;z_fn};
                yp       = diff(y);
                zp       = diff(z);
                
                syms t
                if has(yp,t)
                    yp_fn = matlabFunction(yp);
                else
                    yp_fn = str2func(['@(t)' num2str(double(yp))]);
                end
                if has(zp,t)
                    zp_fn = matlabFunction(zp);
                else
                    zp_fn = str2func(['@(t)' num2str(double(zp))]);
                end
                
                app.dcp  = {0;yp_fn;zp_fn};
            end
            app.div_end   = div_end1;
            app.div_start = div_start1;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.PathtypeListBoxLabel = uilabel(app.UIFigure);
            app.PathtypeListBoxLabel.Text = 'Path type';
            app.PathtypeListBox = uilistbox(app.UIFigure);
            app.PathtypeListBox.Items = {'Select', 'None', 'Constant', 'Oblique', 'Helical', 'Custom'};
            app.PathtypeListBox.ValueChangedFcn = createCallbackFcn(app, @PathtypeListBoxValueChanged, true);
            app.PathtypeListBox.Value = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cableactuationGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end