classdef cableactuationGUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DoneButton            matlab.ui.control.Button
        PathtypeListBoxLabel  matlab.ui.control.Label
        PathtypeListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        done % Description
        S % Description
        dc
        dcp
        Ediv
        iL % Description
        Sdiv % Description
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function closewindow(app)
            while app.done==0
                pause(0.05);
            end
            close(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, S, ii, iL)
            % Create ChoosebasisforthesoftpieceLabel
            Label1          = uilabel(app.UIFigure);
            Label1.Position = [70 250 200 22];
            Label1.Text     = ['Setup path for actuator ',num2str(ii), ' on link ',num2str(iL)];
            app.done=0;
            app.S  = S;
            app.iL = iL;
            
            
            f=1;
            for i=1:iL-1
                f=f+1;
                for j=1:S.VLinks(i).npie-1
                    f=f+1;
                end
            end
            f=f+1; %joint
            ff=f:f+S.VLinks(iL).npie-1;
            app.S.plotq0(ff);
            
            app.closewindow;
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            dci = cell(1,app.S.VLinks(app.iL).npie-1);
            
            if app.Sdiv>0
                syms t;
                d=1;
                for j=1:app.S.VLinks(app.iL).npie-1
                    
                    if j==app.Sdiv
                        x=0;
                    end
                    if j>=app.Sdiv&&j<=app.Ediv
                        nGauss = app.S.VLinks(app.iL).nGauss{j};
                        Xs     = app.S.VLinks(app.iL).Xs{j};
                        lp     = app.S.VLinks(app.iL).lp{j};
                        
                        dci{d}      = zeros(3,nGauss);%at all significant points
                        dci{d}(:,1) = double(subs(app.dc,t,x));
                        for k=2:nGauss
                            x           = x+(Xs(k)-Xs(k-1))*lp;
                            dci{d}(:,k) = double(subs(app.dc,t,x));
                        end
                    end
                    d=d+1;
                end
                CableDisplay(app.dc(2),app.dc(3),app.iL,app.Sdiv,app.Ediv,app.S,1)
            end
            
            close all
            Edivi=app.Ediv;
            Sdivi=app.Sdiv;
            save('cableactuation.mat','dci','Sdivi','Edivi')
            app.done=1;
        end

        % Value changed function: PathtypeListBox
        function PathtypeListBoxValueChanged(app, event)
            
            f=1;
            for i=1:app.iL-1
                f=f+1;
                for j=1:app.S.VLinks(i).npie-1
                    f=f+1;
                end
            end
            f=f+1; %joint
            ff=f:f+app.S.VLinks(app.iL).npie-1;
            app.S.plotq0(ff);
            
            value = app.PathtypeListBox.Value;
            switch value
                
                case 'Constant'
                close all 
                app.S.plotq0(ff);
                
                gooddiv=0;
                while ~gooddiv
                    
                    prompt           = {'y co-ordinate of starting point:',...
                                        'z co-ordinate of starting point:',...
                                        'Division number corresponding to the starting point:',...
                                        'Division number corresponding to the ending point:'};
                    dlgtitle         = 'Actuator parameters for constant path';
                    definput         = {'0.007','0','1',num2str(app.S.VLinks(app.iL).npie-1)};
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'normal';
                    cb_input         = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
                    y1    = str2num(cb_input{1});
                    z1    = str2num(cb_input{2});
                    Sdiv1 = str2num(cb_input{3});
                    Ediv1 = str2num(cb_input{4});
                
                    if Ediv1<Sdiv1||Sdiv1<1||Ediv1>(app.S.VLinks(app.iL).npie-1)
                        gooddiv=0;
                        uiwait(msgbox('INCORRECT DIVISION NUMBER(S)','Error','error'))
                    else
                        gooddiv=1;
                    end
                
                end
                
                    
                x1 = 0;
                x2 = 0;
                for jj=Sdiv1:Ediv1
                    x2=x2+app.S.VLinks(app.iL).lp{jj};
                end

                syms t
                x = t;
                y = sym(y1);
                z = sym(z1);

            
                CableDisplay(y,z,app.iL,Sdiv1,Ediv1,app.S,0)
                app.PathtypeListBox.Value = 'Select';
                figure(app.UIFigure)
                
                case 'Oblique'
                close all 
                app.S.plotq0(ff);
                
                gooddiv=0;
                while ~gooddiv
                    
                    prompt           = {'y co-ordinate of starting point:',...
                                        'z co-ordinate of starting point:',...
                                        'Division number corresponding to the starting point:',...
                                        'y co-ordinate of ending point:',...
                                        'z co-ordinate of ending point:',...
                                        'Division number corresponding to the ending point:'};
                    dlgtitle         = 'Actuator parameters for oblique path';
                    definput         = {'0.007','0','1','0','0.007',num2str(app.S.VLinks(app.iL).npie-1)};
                    opts.Interpreter = 'tex';
                    opts.WindowStyle = 'normal';
                    cb_input         = inputdlg(prompt,dlgtitle,[1 60],definput,opts);
                    y1    = str2num(cb_input{1});
                    z1    = str2num(cb_input{2});
                    Sdiv1 = str2num(cb_input{3});
                    y2    = str2num(cb_input{4});
                    z2    = str2num(cb_input{5});
                    Ediv1 = str2num(cb_input{6});
                
                    if Ediv1<Sdiv1||Sdiv1<1||Ediv1>(app.S.VLinks(app.iL).npie-1)
                        gooddiv=0;
                        uiwait(msgbox('INCORRECT DIVISION NUMBER(S)','Error','error'))
                    else
                        gooddiv=1;
                    end
                
                end
                
                    
                x1 = 0;
                x2 = 0;
                for jj=Sdiv1:Ediv1
                    x2=x2+app.S.VLinks(app.iL).lp{jj};
                end
                
                syms t
                x = t;
                y = y1+(y2-y1)/(x2-x1)*x;
                z = z1+(z2-z1)/(x2-x1)*x;
            
            
                CableDisplay(y,z,app.iL,Sdiv1,Ediv1,app.S,0)
                app.PathtypeListBox.Value = 'Select';
                figure(app.UIFigure)
                
                case 'None'
                    close all
                    app.S.plotq0(ff);
                    
                    Sdiv1 = 0;
                    Ediv1 = 0;
                
            end
            if Sdiv1>0
                app.dc   = [0;y;z];
                app.dcp  = [0,diff(y),diff(z)];
            end
            app.Sdiv = Sdiv1;
            app.Ediv = Ediv1;
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.PathtypeListBoxLabel = uilabel(app.UIFigure);
            app.PathtypeListBoxLabel.Text = 'Path type';
            app.PathtypeListBox = uilistbox(app.UIFigure);
            app.PathtypeListBox.Items = {'Select', 'None', 'Constant', 'Oblique'};
            app.PathtypeListBox.ValueChangedFcn = createCallbackFcn(app, @PathtypeListBoxValueChanged, true);
            app.PathtypeListBox.Value = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cableactuationGUI2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end