classdef custombase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TorsionaboutxaxisCheckBox     matlab.ui.control.CheckBox
        OrderSpinnerLabel             matlab.ui.control.Label
        OrderSpinner                  matlab.ui.control.Spinner
        BendingaboutyaxisCheckBox     matlab.ui.control.CheckBox
        BendingaboutzaxisCheckBox     matlab.ui.control.CheckBox
        OrderSpinner_2Label           matlab.ui.control.Label
        OrderSpinner_2                matlab.ui.control.Spinner
        OrderSpinner_3Label           matlab.ui.control.Label
        OrderSpinner_3                matlab.ui.control.Spinner
        ElongationalongxaxisCheckBox  matlab.ui.control.CheckBox
        OrderSpinner_4Label           matlab.ui.control.Label
        OrderSpinner_4                matlab.ui.control.Spinner
        ShearalongyaxisCheckBox       matlab.ui.control.CheckBox
        OrderSpinner_5Label           matlab.ui.control.Label
        OrderSpinner_5                matlab.ui.control.Spinner
        ShearalongzaxisCheckBox       matlab.ui.control.CheckBox
        OrderSpinner_6Label           matlab.ui.control.Label
        OrderSpinner_6                matlab.ui.control.Spinner
        DoneButton                    matlab.ui.control.Button
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        Label_2                       matlab.ui.control.Label
        TextArea_3                    matlab.ui.control.TextArea
        Label_3                       matlab.ui.control.Label
        TextArea_4                    matlab.ui.control.TextArea
        Label_4                       matlab.ui.control.Label
        TextArea_5                    matlab.ui.control.TextArea
        Label_5                       matlab.ui.control.Label
        TextArea_6                    matlab.ui.control.TextArea
        EnterasafunctionofXX0atstartingpointofthedivisionLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        done % Description
    end
    
    methods (Access = public)
        
        function closewindow(app)
            while app.done==0
                pause(0.05);
            end
            close(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, i, j)
                       % Create ChoosebasisforthesoftpieceLabel
            Label1          = uilabel(app.UIFigure);
            Label1.Position = [125 340 200 22];
            Label1.Text     = ['Choose basis for link ',num2str(i),' division ',num2str(j)]; 
            app.done=0;
            app.closewindow;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            Bdof     = zeros(6,1);
            Bodr     = zeros(6,1);
            xi_stars = cell(6,1);
            if app.TorsionaboutxaxisCheckBox.Value
                Bdof(1) = 1;
            end
            if app.BendingaboutyaxisCheckBox.Value
                Bdof(2) = 1;
            end
            if app.BendingaboutzaxisCheckBox.Value
                Bdof(3) = 1;
            end
            if app.ElongationalongxaxisCheckBox.Value
                Bdof(4) = 1;
            end
            if app.ShearalongyaxisCheckBox.Value
                Bdof(5) = 1;
            end
            if app.ShearalongzaxisCheckBox.Value
                Bdof(6) = 1;
            end
            Bodr(1) = app.OrderSpinner.Value;
            Bodr(2) = app.OrderSpinner_2.Value;
            Bodr(3) = app.OrderSpinner_3.Value;
            Bodr(4) = app.OrderSpinner_4.Value;
            Bodr(5) = app.OrderSpinner_5.Value;
            Bodr(6) = app.OrderSpinner_6.Value;
            
            xi_stars{1} = app.TextArea.Value;
            xi_stars{2} = app.TextArea_2.Value;
            xi_stars{3} = app.TextArea_3.Value;
            xi_stars{4} = app.TextArea_4.Value;
            xi_stars{5} = app.TextArea_5.Value;
            xi_stars{6} = app.TextArea_6.Value;
            
            save('Base_properties.mat','Bdof','Bodr','xi_stars')
            app.done=1;
        end

        % Value changed function: TorsionaboutxaxisCheckBox
        function TorsionaboutxaxisCheckBoxValueChanged(app, event)
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TorsionaboutxaxisCheckBox = uicheckbox(app.UIFigure);
            app.TorsionaboutxaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @TorsionaboutxaxisCheckBoxValueChanged, true);
            app.TorsionaboutxaxisCheckBox.Text = 'Torsion about x axis';
            app.OrderSpinnerLabel = uilabel(app.UIFigure);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.UIFigure);
            app.OrderSpinner.Limits = [0 Inf];
            app.OrderSpinner.RoundFractionalValues = 'on';
            app.OrderSpinner.ValueDisplayFormat = '%.0f';
            app.BendingaboutyaxisCheckBox = uicheckbox(app.UIFigure);
            app.BendingaboutyaxisCheckBox.Text = 'Bending about y axis';
            app.BendingaboutzaxisCheckBox = uicheckbox(app.UIFigure);
            app.BendingaboutzaxisCheckBox.Text = 'Bending about z axis';
            app.OrderSpinner_2Label = uilabel(app.UIFigure);
            app.OrderSpinner_2Label.Text = 'Order';
            app.OrderSpinner_2 = uispinner(app.UIFigure);
            app.OrderSpinner_2.Limits = [0 Inf];
            app.OrderSpinner_2.RoundFractionalValues = 'on';
            app.OrderSpinner_2.ValueDisplayFormat = '%.0f';
            app.OrderSpinner_3Label = uilabel(app.UIFigure);
            app.OrderSpinner_3Label.Text = 'Order';
            app.OrderSpinner_3 = uispinner(app.UIFigure);
            app.OrderSpinner_3.Limits = [0 Inf];
            app.OrderSpinner_3.RoundFractionalValues = 'on';
            app.OrderSpinner_3.ValueDisplayFormat = '%.0f';
            app.ElongationalongxaxisCheckBox = uicheckbox(app.UIFigure);
            app.ElongationalongxaxisCheckBox.Text = 'Elongation along x axis';
            app.OrderSpinner_4Label = uilabel(app.UIFigure);
            app.OrderSpinner_4Label.Text = 'Order';
            app.OrderSpinner_4 = uispinner(app.UIFigure);
            app.OrderSpinner_4.Limits = [0 Inf];
            app.OrderSpinner_4.RoundFractionalValues = 'on';
            app.OrderSpinner_4.ValueDisplayFormat = '%.0f';
            app.ShearalongyaxisCheckBox = uicheckbox(app.UIFigure);
            app.ShearalongyaxisCheckBox.Text = 'Shear along y axis';
            app.OrderSpinner_5Label = uilabel(app.UIFigure);
            app.OrderSpinner_5Label.Text = 'Order';
            app.OrderSpinner_5 = uispinner(app.UIFigure);
            app.OrderSpinner_5.Limits = [0 Inf];
            app.OrderSpinner_5.RoundFractionalValues = 'on';
            app.OrderSpinner_5.ValueDisplayFormat = '%.0f';
            app.ShearalongzaxisCheckBox = uicheckbox(app.UIFigure);
            app.ShearalongzaxisCheckBox.Text = 'Shear along z axis';
            app.OrderSpinner_6Label = uilabel(app.UIFigure);
            app.OrderSpinner_6Label.Text = 'Order';
            app.OrderSpinner_6 = uispinner(app.UIFigure);
            app.OrderSpinner_6.Limits = [0 Inf];
            app.OrderSpinner_6.RoundFractionalValues = 'on';
            app.OrderSpinner_6.ValueDisplayFormat = '%.0f';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'ξ*(1)';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'0'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ξ*(2)';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'0'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'ξ*(3)';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'0'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'ξ*(4)';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'1'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'ξ*(5)';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'0'};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'ξ*(6)';
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.Value = {'0'};
            app.EnterasafunctionofXX0atstartingpointofthedivisionLabel = uilabel(app.UIFigure);
            app.EnterasafunctionofXX0atstartingpointofthedivisionLabel.Text = 'Enter reference strain (ξ*) as a function of normalized X (varies from 0 to 1)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = custombase(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end